classdef MDO34Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SweepPanel                     matlab.ui.container.Panel
        range1100Label                 matlab.ui.control.Label
        range10002E8Label              matlab.ui.control.Label
        PointsDecadeEditField          matlab.ui.control.NumericEditField
        PointsDecadeEditFieldLabel     matlab.ui.control.Label
        StopFreqEditField              matlab.ui.control.NumericEditField
        StopFreqEditFieldLabel         matlab.ui.control.Label
        StartFreqEditField             matlab.ui.control.NumericEditField
        StartFreqEditFieldLabel        matlab.ui.control.Label
        range101E8Label                matlab.ui.control.Label
        ScopePanel                     matlab.ui.container.Panel
        InputButtonGroup               matlab.ui.container.ButtonGroup
        SquareButton                   matlab.ui.control.RadioButton
        SineButton                     matlab.ui.control.RadioButton
        PhaseLabel                     matlab.ui.control.Label
        VoutVinLabel                   matlab.ui.control.Label
        FreqHzEditFieldLabel           matlab.ui.control.Label
        FreqHzEditField                matlab.ui.control.NumericEditField
        range102E8Label                matlab.ui.control.Label
        LabelGENAddress                matlab.ui.control.Label
        ChooseGeneratorButtonGroup     matlab.ui.container.ButtonGroup
        kHz200MHzLabel                 matlab.ui.control.Label
        Hz80MHzLabel                   matlab.ui.control.Label
        SML03Button                    matlab.ui.control.RadioButton
        A33250Button                   matlab.ui.control.RadioButton
        MDO34GenSignalAnalyzerToolLabel  matlab.ui.control.Label
        TestsettingsPanel              matlab.ui.container.Panel
        OscilloscopeterminationLabel   matlab.ui.control.Label
        CH3OutputButtonGroup           matlab.ui.container.ButtonGroup
        MOhmButton_2                   matlab.ui.control.RadioButton
        OhmButton_2                    matlab.ui.control.RadioButton
        CH1InputButtonGroup            matlab.ui.container.ButtonGroup
        MOhmButton                     matlab.ui.control.RadioButton
        OhmButton                      matlab.ui.control.RadioButton
        range00011Label                matlab.ui.control.Label
        AmplitudeVrmsEditField         matlab.ui.control.NumericEditField
        AmplitudeVrmsEditFieldLabel    matlab.ui.control.Label
        slowWarningLabel               matlab.ui.control.Label
        GoButton                       matlab.ui.control.Button
        ModeSwitch                     matlab.ui.control.ToggleSwitch
        SaveButton                     matlab.ui.control.Button
        LabelScopeAddress              matlab.ui.control.Label
        Caltech2024RoukesGroupLabel    matlab.ui.control.Label
        SetAnalysisParametersandStartButton  matlab.ui.control.Button
        InitializationPleasewaitLabel  matlab.ui.control.Label
        UIAxesPhs                      matlab.ui.control.UIAxes
        UIAxesMag                      matlab.ui.control.UIAxes
        UIAxesTime                     matlab.ui.control.UIAxes
    end

    properties (Access = private)
        initialized % false before Initialize button pushed
        geninitialized %status of initialized generator
        scope % oscilloscope visa object
        generator % generator visa object
        SinSqu %type of the current signal
        currentCH1Termination %status of the current termination chosen
        currentCH3Termination %status of the current termination chosen
        tVec  % time values
        fVec  % frequency values
        Ch1Vec  % Vin(t)
        CH3Vec  % Vout(t)
        magVec  % |H(jw)|
        phsVec  % angle(H(jw))
        sweepActive  % This is true during a sweep
    end
    
    methods (Access = private)
        
        % This function gets data from scope and plots it.
        % It also calculates ratio of input to output, as well as phase
        % difference.
        function [magH, phsH] = getTimeDomain(app)
            app.ModeSwitch.Enable = false;
            
            freq = app.FreqHzEditField.Value;
            %%%%%%%%%%%%%%%%%%%%%%%%
            %fprintf(app.scope, ['AFG:FREQUENCY ' num2str(freq)]);
            %strr = append("APPL:SIN ",num2str(freq),", 1.0 Vpp, 0")
            %disp(app);
            %p= app.OhmButton.Value;
            if app.A33250Button.Value
                fprintf(app.generator,['APPL:' num2str(app.SinSqu) ' ' num2str(freq) ', ' num2str(app.AmplitudeVrmsEditField.Value) ', 0']);
                %r = query(app.generator, '*ESR?');
            else
                %r = query(app.generator, '*esr?');
                fprintf(app.generator, [':POW ' num2str(app.AmplitudeVrmsEditField.Value) ' V']);
                %r = query(app.generator, '*esr?'); 
                fprintf(app.generator, [':SOUR:FREQ:MAN ' num2str(freq)]);
                %r = query(app.generator, '*esr?');  
            end

            timePerDiv = 1/2/freq;  % 8 divisions on screen, so this is roughly four periods
            r = query(app.scope, '*esr?');
            fprintf(app.scope, ['HORIZONTAL:SCALE ' num2str(timePerDiv)]);
            timePerDiv = str2double(query(app.scope, 'HORIZONTAL:SCALE?'));
            r = query(app.scope, '*esr?');


            % Show warning if freq < 100.
                app.slowWarningLabel.Visible = freq < 100;

            
            pause(1);        % Pause for 1 sec plus...
            pause(40*timePerDiv);  % ... plus several cycles to reach equilibrium.
            
            fprintf(app.scope, 'DATA:Source CH1');
            r = query(app.scope, '*esr?');

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            done1 = false;
            while ~done1   % This while loop performs autoscaling
                voltPerDiv = str2double(query(app.scope,'CH1:SCALE?'));
                r = query(app.scope, '*esr?');               
                % pause enought time for scope to trigger.  If there were a
                % way to determine when trigger occurs, this could be
                % avoided.  Scope can also be set for single acquisition,
                % but I didn't pursue that.
                %pause(40*timePerDiv);  
                r = query(app.scope, '*esr?');
                %fprintf(app.scope, 'ACQuire:STOPAfter SEQuence');
                r = query(app.scope, '*esr?');
                fprintf(app.scope, 'CURVE?');
                %pause(40*timePerDiv);  
                %r = query(app.scope, '*esr?');
                % Read the captured data as 8-bit integer data type
                outBin = binblockread(app.scope,'int8');
                fread(app.scope, 1);
                %pause(40*timePerDiv);  
                r = query(app.scope, '*esr?');     
                r = query(app.scope, '*esr?');  
                if max(outBin) < 32  % if input is too small, increase scale
                    if voltPerDiv <= 0.002  % Minimum scale is 1 mV/div
                        done1 = true;
                    else
                        voltPerDiv = voltPerDiv/2;
                        fprintf(app.scope,['CH1:SCALE ' num2str(voltPerDiv)]);
                        r = query(app.scope, '*esr?'); 
                        pause(1);
                    end
                elseif max(outBin) > 100  % if too big, decrease scale
                    if voltPerDiv >= 5 % Max scale is 10 V/div
                        done1 = true;
                    else
                        voltPerDiv = voltPerDiv*2;
                        fprintf(app.scope,['CH1:SCALE ' num2str(voltPerDiv)]);
                        pause(1);
                    end
                else  % if output is good size, just leave
                    done1 = true;
                end
            end


            %a = query(app.scope, 'WFMOutpre:NR_Pt?');
            %r = query(app.scope, '*esr?');
            %fprintf(app.scope, ':WFMINPRE:NR_PT 10000');
            %r = query(app.scope, '*esr?');
            %b = query(app.scope, 'WFMOutpre:NR_Pt?');

            verticalScale  = query(app.scope,'WFMOUTPRE:YMULT?');
            %%%%%%%%
            recordLength = query(app.scope, 'HOR:RECO?');
            % Ensure that the start and stop values for CURVE query match the full
            % record length
            %fprintf(app.scope, ['DATA:START 1;DATA:STOP ' recordLength]);
            % Request 8 bit binary data on the CURVE query
            fprintf(app.scope, 'DATA:ENCDG RIBINARY;WIDTH 1');
            %fprintf(app.scope, 'ACQUIRE:STOPAFTER SEQUENCE');
            fprintf(app.scope, 'CURVE?');


            %pause(1);        % Pause for 1 sec plus...
            %pause(40*timePerDiv);  % ... plus several cycles to reach equilibrium.

            %a = fread(app.scope,1);
            %disp(a);
            %fprintf(app.scope, 'FORM:DATA?'); 
            % Read the captured data as 8-bit integer data type
            %properties(app.scope);
            %get(app.scope);
            %inChan = (str2double(verticalScale) * binblockread(app.scope,'int8'));
            inChan = (str2double(verticalScale) * binblockread(app.scope,'int8'))';
            fread(app.scope, 1);
            %r = query(app.scope, '*esr?');
            %fprintf('event status register: %s\n', strtrim(r));
            %r = query(app.scope, 'allev?');
            %fprintf('all event messages: %s\n', strtrim(r));
            %if isempty(inChan)
            %error('binblockread: An error occurred while reading the waveform.');
            %end
            %disp(inChan)
            %C = char('A');
            %disp(C)
            %disp(inChan2)
            fprintf(app.scope, '*WAI; DATA:Source CH3');
            done = false;
            while ~done   % This while loop performs autoscaling
                voltPerDiv = str2double(query(app.scope,'CH3:SCALE?'));
                                
                % pause enought time for scope to trigger.  If there were a
                % way to determine when trigger occurs, this could be
                % avoided.  Scope can also be set for single acquisition,
                % but I didn't pursue that.
                pause(40*timePerDiv);  
         
                fprintf(app.scope, 'CURVE?');
            
                % Read the captured data as 8-bit integer data type
                outBin = binblockread(app.scope,'int8');
                fread(app.scope, 1);
                       
                if max(outBin) < 32  % if input is too small, increase scale
                    if voltPerDiv <= 0.002  % Minimum scale is 1 mV/div
                        done = true;
                    else
                        voltPerDiv = voltPerDiv/2;
                        fprintf(app.scope,['CH3:SCALE ' num2str(voltPerDiv)]);
                        pause(1);
                    end
                elseif max(outBin) > 100  % if too big, decrease scale
                    if voltPerDiv >= 5 % Max scale is 10 V/div
                        done = true;
                    else
                        voltPerDiv = voltPerDiv*2;
                        fprintf(app.scope,['CH3:SCALE ' num2str(voltPerDiv)]);
                        pause(1);
                    end
                else  % if output is good size, just leave
                    done = true;
                end
            end
%             
%             fprintf(app.scope, 'CURVE?');
%             % Read the captured data as 8-bit integer data type
%             outBin = binblockread(app.scope,'int8');
            r = query(app.scope, '*esr?');
            verticalScale  = query(app.scope,'WFMOUTPRE:YMULT?');
            outChan = (str2double(verticalScale) * outBin)';
            r = query(app.scope, '*esr?');
            deltaT = str2double(query(app.scope,'WFMOUTPRE:XINCR?'));
            r = query(app.scope, '*esr?');
            t0 = str2double(query(app.scope,'WFMOUTPRE:XZERO?'));
            r = query(app.scope, '*esr?');
            %%%%%%%%%%%%%%%
            t01 = str2double(query(app.scope, 'HORizontal:SCAle?'));
            t01 = -t01*5;
            %t0 = t01;
            %disp(t0);
            t = t0 + deltaT * (0:length(outChan)-1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            tInds = abs(t)<abs(t01);
            %disp(tInds);
            tFits = t(tInds);
            inChanFits = inChan(tInds);
            outChanFits = outChan(tInds);
          

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            plot(app.UIAxesTime,tFits,inChanFits,"Color",[1 1 0]);
            hold(app.UIAxesTime,'on');
            plot(app.UIAxesTime,tFits,outChanFits,"Color",[1 0 0]);
            hold(app.UIAxesTime,'off');
            grid(app.UIAxesTime,'on');
            set(app.UIAxesTime,'Color','k');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Find max volts per div and use that to scale display
            voltPerDiv = max(str2double(query(app.scope,'CH1:SCALE?')),...
                str2double(query(app.scope,'CH3:SCALE?')));
            app.UIAxesTime.YTick = -4*voltPerDiv:voltPerDiv:4*voltPerDiv;
            app.UIAxesTime.YLim = [-4*voltPerDiv 4*voltPerDiv];
            
            app.UIAxesTime.XTick = -5*timePerDiv:timePerDiv:5*timePerDiv;
            
            legend(app.UIAxesTime,{'V_{in}' 'V_{out}'},'TextColor','w','EdgeColor','w');
            
            title(app.UIAxesTime, sprintf('Time Domain Signal f=%6.0f Hz',freq));
                     
            % get exactly 2 oscillations - this seems to help with
            % accuracy.  Maybe something to do with spectral leakage?
            tInd = abs(t)<1/freq;
            tFit = t(tInd);
            inChanFit = inChan(tInd);
            outChanFit = outChan(tInd);
            % Get DTFT of two signals to get mag and phase at this freq
            aaa = [inChanFit; outChanFit];
            bbb = transpose(exp(-1j*2*pi*freq*tFit));
            DTFT = aaa*bbb;
            
            magH = abs(DTFT(2)/DTFT(1));
            app.VoutVinLabel.Text = sprintf('|Vout/Vin| = %.2e = %.4g dB', magH, mag2db(magH));
            
            phsH = rad2deg(angle(DTFT(2)/DTFT(1)));
            app.PhaseLabel.Text = sprintf('%c = %.4g%c', char(952), phsH, char(176));
            
            % Save results
            app.Ch1Vec = inChan;
            app.CH3Vec = outChan;
            app.tVec = t;
            
            app.SaveButton.Enable = true;
            app.GoButton.Enable = true;
            app.ModeSwitch.Enable = true;

            app.InitializationPleasewaitLabel.Visible = 0;

        end
        
        function getFreqDomain(app)
            app.ModeSwitch.Enable = false;
            app.SaveButton.Enable = false;
            app.GoButton.Enable = false;
            
            startVal = log10(app.StartFreqEditField.Value);
            incrVal = 1/app.PointsDecadeEditField.Value;
            endVal = log10(app.StopFreqEditField.Value) + incrVal/2;  % Make end a little high to make sure rounding not an issue.
            f = round(10.^(startVal:incrVal:endVal));
            ttt = abs(f)<app.StopFreqEditField.Value;
            %disp(ttt);
            f = f(ttt);
            app.fVec = f;
            app.magVec = zeros(size(f));
            app.phsVec = zeros(size(f));

            app.UIAxesMag.YLim(1) = -10;
            app.UIAxesMag.YLim(2) = 10;
            app.UIAxesPhs.YLim(1) = -10;
            app.UIAxesPhs.YLim(2) = 10;

            for i=1:length(f)
                if ~app.sweepActive
                    break;
                end
                %disp(i)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.FreqHzEditField.Value = f(i);
                [m,p] = getTimeDomain(app);
                app.magVec(i) = m;
                app.phsVec(i) = p;
                
                semilogx(app.UIAxesMag, f(1:i),mag2db(app.magVec(1:i)));
                app.UIAxesMag.XLim = [min(f) max(f)];

                %%%%%%%%%%%%%%%%%%%
              
                tempminYm = app.UIAxesMag.YLim(1);
                tempmaxYm = app.UIAxesMag.YLim(2);
                

                %%%%%%%%%%%%%%%%%%%%%%%
                app.UIAxesMag.YLim = [min(min(mag2db(m)-10),-10)...
                    max(max(mag2db(m))+10,10)];
                if app.UIAxesMag.YLim(1) > tempminYm
                    app.UIAxesMag.YLim(1) = tempminYm;
                end

                if app.UIAxesMag.YLim(2) < tempmaxYm;
                    app.UIAxesMag.YLim(2) = tempmaxYm;
                end

                tempminYm = app.UIAxesMag.YLim(1);
                tempmaxYm = app.UIAxesMag.YLim(2);


                tempminYph = app.UIAxesPhs.YLim(1);
                tempmaxYph = app.UIAxesPhs.YLim(2);

                semilogx(app.UIAxesPhs, f(1:i),app.phsVec(1:i));
                app.UIAxesPhs.XLim = [min(f) max(f)];
                app.UIAxesPhs.YLim = [min(min(p)-10,-10) max(max(p)+10,10)];

                if app.UIAxesPhs.YLim(1) > tempminYph
                    app.UIAxesPhs.YLim(1) = tempminYph;
                end

                if app.UIAxesPhs.YLim(2) < tempmaxYph;
                    app.UIAxesPhs.YLim(2) = tempmaxYph;
                end


                tempminYph = app.UIAxesPhs.YLim(1);
                tempmaxYph = app.UIAxesPhs.YLim(2);


            end
            
            
            if app.sweepActive % normal finish
                app.SaveButton.Enable = true;
            end
            
            app.ModeSwitch.Enable = true;

            app.InitializationPleasewaitLabel.Visible = 0; %The Initialisation process is now completed
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            fid = fopen('settings.txt');
            t = fgetl(fid);
                if ischar(t)
                    if matches(t,'true')
                        %app.A33250Button.Value = 0;
                        app.SML03Button.Value = 1;
                    end
                    if matches(t,'false')
                        app.A33250Button.Value = 1;
                    end
                end
            fclose(fid);

            %text = fileread('settings.txt');
            %app.GeneratorVISAAddressEditField.Value = text;
            %fid = fop
            %c = textscan(text,string);


            app.geninitialized = false;
            cla(app.UIAxesTime, 'reset');
            set(app.UIAxesTime,'Color','k');
            app.UIAxesMag.Visible = 0;
%             app.UIAxesMag.Box = 1;
%             grid(app.UIAxesMag,'on');
            app.UIAxesPhs.Visible = 0;
%             app.UIAxesPhs.Box = 1;
%             grid(app.UIAxesPhs,'on');
            
            app.initialized = false; % prevent application from execution without proper settings, the application start to work only after "Initialize and Start" button pushed
            app.InitializationPleasewaitLabel.Visible = 0;
            app.ModeSwitch.Enable = false;
           
            app.UIAxesTime.Visible = 0;
            app.UIAxesMag.Visible = 0;
            app.UIAxesPhs.Visible = 0;
            app.slowWarningLabel.Visible = 0;
            set(app.UIAxesTime,'Color','k');
            app.ModeSwitch.Value='Scope';
            app.ScopePanel.Visible = 1;
            app.SweepPanel.Visible = 0;
            
%            app.UIAxesMag.Visible = 0;
%             app.UIAxesMag.Box = 1;
%             grid(app.UIAxesMag,'on');
%            app.UIAxesPhs.Visible = 0;
%             app.UIAxesPhs.Box = 1;
%             grid(app.UIAxesPhs,'on');
            
%           app.slowWarningLabel.Visible = 0;

            app.SaveButton.Enable = 0;
            app.GoButton.Enable = 0;
            app.sweepActive = false;

            c = 0;
            while c < 1000
                pause(.1)
                %disp(c)
                c = c+1;
                % Check if state button was pressed (from false to true)
                if app.initialized
                    c = 1001; % short circuit the while-loop
                end
                app.InitializationPleasewaitLabel.Visible = 1;
            end
          
            fid = fopen( 'settings.txt', 'wt');
            fprintf(fid, string(app.SML03Button.Value));
            fclose(fid);

            app.InitializationPleasewaitLabel.Visible = 1;
            app.UIAxesTime.Visible = 1;

            
            %uiwait(app.SetAnalysisParametersandStartButton,240);

            instrreset;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            resourceList = visadevlist;
            gen_visaAddr = [];
            gen_Model = [];
            for i = 1 : numel(resourceList.ResourceName)
                gen_cName = resourceList.Model{i};
                    if app.SML03Button.Value
                        gen_Model = regexp(gen_cName,'SML03','match');
                    elseif app.A33250Button.Value
                        gen_Model = regexp(gen_cName,'33250A','match');                      
                    end
                    if ~isempty(gen_Model)
                        gen_visaAddr = resourceList.ResourceName{i};
                        break
                    end
            end

            if isempty(gen_visaAddr)
                f = errordlg('Couldn''t connect','Cannot connect to generator');
                uiwait(f);
                closereq;
                return;
            end

            app.LabelGENAddress.Text = ['Gen VISA Address: ' gen_visaAddr];

            app.generator = visadev(gen_visaAddr);
            app.geninitialized = true;
            instrreset;
            fprintf(app.generator, '*RST');
            r = query(app.generator, '*esr?');
            %writeline(app.generator,"*IDN?")
            app.SinSqu = 'SIN';
            freq = app.FreqHzEditField.Value;
            %freqMHz = freq/1000000;
            if app.A33250Button.Value
                fprintf(app.generator, 'VOLTage:UNIT VRMS');
                fprintf(app.generator,['APPL:' num2str(app.SinSqu) ' ' num2str(freq) ', ' num2str(app.AmplitudeVrmsEditField.Value) ', 0']);
                %r = query(app.generator, '*esr?');
            else
                fprintf(app.generator, ':SOUR:FREQ:STAR 9kHz'); 
                fprintf(app.generator, [':POW ' num2str(app.AmplitudeVrmsEditField.Value) ' V']);
                r = query(app.generator, '*esr?');
                fprintf(app.generator, [':SOUR:FREQ:MAN ' num2str(freq)]);
                r = query(app.generator, '*esr?');
                fprintf(app.generator, 'OUTP:STAT ON');
                r = query(app.generator, '*esr?');
               
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Find the first device on USB with proper vendo (0x0699) and model (0x052c).
            visaAddr = [];
            for i = 1 : numel(resourceList.ResourceName)
                cName = resourceList.ResourceName{i};
                visaAddr = regexp(cName,'USB0::0x0699::0x052C','match');
                if ~isempty(visaAddr)
                    visaAddr = cName;
                    break
                end
            end

            if isempty(visaAddr)
                f = errordlg('Couldn''t connect','Cannot connect to scope');
                uiwait(f);
                closereq;
                return;
            end
            
            app.LabelScopeAddress.Text = ['MDO34 USB Address: ' visaAddr];
            %app.scope = visadev(visaAddr{1});
            app.scope = visa('ni', visaAddr);
            app.scope.InputBufferSize = 100000;
            app.scope.OutputBufferSize = 100000;    
            app.scope.Timeout = 4;
            
            % Open the connection to the oscilloscope
            fopen(app.scope);
            
            % Reset the oscilloscope to a known state
            fprintf(app.scope, '*RST');
            fprintf(app.scope, '*CLS');
            %r = query(app.scope, '*esr?');
            fprintf(app.scope, 'CH1:SCALE 0.5');
            fprintf(app.scope, 'CH3:SCALE 0.5');
            
            %fprintf(app.scope, 'AFG:OUTPUT:STATE ON');
            %fprintf(app.scope, 'AFG:AMPLITUDE 2');  % peak to peak
            
            
            fprintf(app.scope, 'HEADER OFF');     % No headers to make parsing easier
            fprintf(app.scope, 'DATA:ENCDG RIBINARY;WIDTH 1');
            %r = query(app.scope, '*esr?');
            % Ensure that start and stop values for CURVE match full record length
            %recordLength = query(app.scope, 'HOR:RECO?');
            %r = query(app.scope, '*esr?');
            fprintf(app.scope, 'DATA:START 1');
            %r = query(app.scope, '*esr?');
            fprintf(app.scope, 'DATA:STOP 10000');
            %r = query(app.scope, '*esr?');
            
            app.SineButton.Value = 1;
            app.OhmButton.Value = 1;
            app.OhmButton_2.Value = 1;
            %fprintf(app.scope, 'AFG:FUNCTION SINE');
            if app.A33250Button.Value
                fprintf(app.generator,['APPL:SIN 10000, 0.05 Vpp, 0']);
            end
            
            fprintf(app.scope, 'SELECT:CH1 ON');
            %fprintf(app.scope, 'CH1:TERmination FIF');
            %r = query(app.scope, '*esr?');

            if app.OhmButton.Value
                    fprintf(app.scope, 'CH1:TERmination FIF');
            else
                    fprintf(app.scope, 'CH1:TERmination MEG');
            end
            app.currentCH1Termination = app.OhmButton.Value;

            fprintf(app.scope, 'SELECT:CH3 ON');
            %fprintf(app.scope,'CH3:TERMINATION FIFty');

            if app.OhmButton_2.Value
                    fprintf(app.scope, 'CH3:TERmination FIF');
            else
                    fprintf(app.scope, 'CH3:TERmination MEG');
            end
                    
            app.currentCH3Termination = app.OhmButton_2.Value;

            %r = query(app.scope, '*esr?');
            %p= (query(app.scope, 'CH3:TERmination?'));
            %r = query(app.scope, '*esr?');
            %r = query(app.scope, '*esr?');

            fprintf(app.scope, 'MEASURE:MEAS1:SOURCE CH1');
            fprintf(app.scope, 'MEASURE:MEAS1:TYPE FREQ');
            fprintf(app.scope, 'MEASURE:MEAS1:STATE ON');
            
            fprintf(app.scope, 'MEASURE:MEAS2:SOURCE CH1');
            fprintf(app.scope, 'MEASURE:MEAS2:TYPE PK2PK');
            fprintf(app.scope, 'MEASURE:MEAS2:STATE ON');
            
            fprintf(app.scope, 'MEASURE:MEAS3:SOURCE CH3');
            fprintf(app.scope, 'MEASURE:MEAS3:TYPE PK2PK');
            fprintf(app.scope, 'MEASURE:MEAS3:STATE ON');
            
            
            fprintf(app.scope, 'MEASURE:MEAS4:SOURCE1 CH3');
            fprintf(app.scope, 'MEASURE:MEAS4:SOURCE2 CH1');
            fprintf(app.scope, 'MEASURE:MEAS4:TYPE PHASE');
            fprintf(app.scope, 'MEASURE:MEAS4:STATE ON');
            r = query(app.scope, '*esr?');
            %%%%%%%%%%%%%%%%%%%%%

            fprintf(app.scope, 'TRIGGER:A:MODE NORMAL');
            %r = query(app.scope, '*esr?');

            
            getTimeDomain(app);
            
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            app.SaveButton.Enable = 0;
            cla(app.UIAxesMag);
            cla(app.UIAxesPhs);
            if strfind('Sweep',app.ModeSwitch.Value)
                app.ScopePanel.Visible = 0;
                app.SweepPanel.Visible = 1;
                
                app.UIAxesMag.Visible = 1;
                app.UIAxesPhs.Visible = 1;
                
                app.SineButton.Value = 1;
                app.SquareButton.Value = 0;
                InputButtonGroupSelectionChanged(app);
            else
                app.ScopePanel.Visible = 1;
                app.SweepPanel.Visible = 0;
                
                app.UIAxesMag.Visible = 0;
                app.UIAxesPhs.Visible = 0;
            end
        end

        % Selection changed function: InputButtonGroup
        function InputButtonGroupSelectionChanged(app, event)
            if app.SineButton.Value == 1
                %fprintf(app.scope, 'AFG:FUNCTION SINE');
                app.SinSqu = 'SIN';
                if app.A33250Button.Value
                    fprintf(app.generator,'APPL:SIN');
                end
                app.VoutVinLabel.Visible = 1;
                app.PhaseLabel.Visible = 1;
            else
                %fprintf(app.scope, 'AFG:FUNCTION SQUARE');
                app.SinSqu = 'SQU';
                if app.A33250Button.Value
                    fprintf(app.generator,'APPL:SQU');
                end
                %r = query(app.generator, '*esr?');
                %disp(r);
                app.VoutVinLabel.Visible = 0;
                app.PhaseLabel.Visible = 0;
            end
            getTimeDomain(app);
        end

        % Value changed function: FreqHzEditField
        function FreqHzEditFieldValueChanged(app, event)
            getTimeDomain(app);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            cla(app.UIAxesMag);
            cla(app.UIAxesPhs);
            app.SaveButton.Enable = false;
            app.ModeSwitch.Enable = false;
            if strfind('Sweep',app.ModeSwitch.Value)
                if ~app.sweepActive
                    app.sweepActive = true;
                    app.GoButton.Text = 'Stop';
                    getFreqDomain(app);
                end
                app.sweepActive=false;              
                app.GoButton.Text = 'Go';
            else
                getTimeDomain(app);
            end
            app.ModeSwitch.Enable = true;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if strfind('Sweep',app.ModeSwitch.Value)
                f = app.fVec;  mag = app.magVec;  phs = app.phsVec;
                [file,path]=uiputfile('*.mat','Save Sweep data','mySweepData.mat');
                if file~=0
                    save([path file],'f', 'mag', 'phs');
                end
            else
                t = app.tVec;   f = app.FreqHzEditField.Value;
                Vin = app.Ch1Vec;   Vout = app.CH3Vec; 
                [file,path]=uiputfile('*.mat','Save scope data','myScopeData.mat');
                if file~=0
                    save([path file],'t', 'f', 'Vin', 'Vout');
                end
            end
        end

        % Selection changed function: CH1InputButtonGroup
        function CH1InputButtonGroupSelectionChanged(app, event)
            selectedButton = app.CH1InputButtonGroup.SelectedObject;
            if app.OhmButton.Value ~= app.currentCH1Termination
                if app.OhmButton.Value
                    fprintf(app.scope, 'CH1:TERmination FIF');
                else
                    fprintf(app.scope, 'CH1:TERmination MEG');
                end
            end

            app.currentCH1Termination = app.OhmButton.Value;
            
        end

        % Selection changed function: CH3OutputButtonGroup
        function CH3OutputButtonGroupSelectionChanged(app, event)
            selectedButton = app.CH3OutputButtonGroup.SelectedObject;
            if app.OhmButton_2.Value ~= app.currentCH3Termination
                if app.OhmButton_2.Value
                    fprintf(app.scope, 'CH3:TERmination FIF');
                else
                    fprintf(app.scope, 'CH3:TERmination MEG');
                end
            end

            app.currentCH3Termination = app.OhmButton_2.Value;
        end

        % Value changed function: AmplitudeVrmsEditField
        function AmplitudeVrmsEditFieldValueChanged(app, event)
            value = app.AmplitudeVrmsEditField.Value;
            getTimeDomain(app);
        end

        % Button pushed function: SetAnalysisParametersandStartButton
        function SetAnalysisParametersandStartButtonPushed(app, event)
            app.initialized = true;
            app.SetAnalysisParametersandStartButton.Visible = false;
        end

        % Callback function
        function GeneratorVISAAddressEditFieldValueChanged(app, event)
            if app.geninitialized 
                %delete(app);
                fid = fopen( 'settings.txt', 'wt');
                fprintf(fid, string(app.SML03Button.Value));
                fclose(fid);
                delete(app.UIFigure);
                %clear app;
                MDO34Gui();
            end
        end

        % Selection changed function: ChooseGeneratorButtonGroup
        function ChooseGeneratorButtonGroupSelectionChanged(app, event)
            if app.geninitialized 
                %delete(app);
                fid = fopen( 'settings.txt', 'wt');
                fprintf(fid, string(app.SML03Button.Value));
                fclose(fid);
                delete(app.UIFigure);
                %clear app;
                MDO34Gui();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesTime = uiaxes(app.UIFigure);
            title(app.UIAxesTime, 'Time Domain Signal')
            xlabel(app.UIAxesTime, 't')
            ylabel(app.UIAxesTime, 'v_{in}(t) & v_{out}(t)')
            app.UIAxesMag = uiaxes(app.UIFigure);
            title(app.UIAxesMag, 'Magnitude')
            xlabel(app.UIAxesMag, 'Frequency (Hz)')
            ylabel(app.UIAxesMag, '|v_{out}/v_{in}|_{dB}')
            app.UIAxesPhs = uiaxes(app.UIFigure);
            title(app.UIAxesPhs, 'Phase')
            xlabel(app.UIAxesPhs, 'Frequency(Hz)')
            ylabel(app.UIAxesPhs, '\theta^o')
            app.InitializationPleasewaitLabel = uilabel(app.UIFigure);
            app.InitializationPleasewaitLabel.Text = {'Initialization....'; 'Please wait'};
            app.SetAnalysisParametersandStartButton = uibutton(app.UIFigure, 'push');
            app.SetAnalysisParametersandStartButton.ButtonPushedFcn = createCallbackFcn(app, @SetAnalysisParametersandStartButtonPushed, true);
            app.SetAnalysisParametersandStartButton.Text = {'Set Analysis Parameters'; ' and Start'};
            app.Caltech2024RoukesGroupLabel = uilabel(app.UIFigure);
            app.Caltech2024RoukesGroupLabel.Text = 'Caltech 2024 - Roukes Group ';
            app.LabelScopeAddress = uilabel(app.UIFigure);
            app.LabelScopeAddress.Text = 'MDO34 USB Address';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ModeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ModeSwitch.Items = {'Sweep', 'Scope'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Scope';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.slowWarningLabel = uilabel(app.UIFigure);
            app.slowWarningLabel.Text = 'Warning: At low frequencies data collection can be (very) slow.';
            app.TestsettingsPanel = uipanel(app.UIFigure);
            app.TestsettingsPanel.Title = 'Test settings';
            app.AmplitudeVrmsEditFieldLabel = uilabel(app.TestsettingsPanel);
            app.AmplitudeVrmsEditFieldLabel.Text = 'Amplitude Vrms';
            app.AmplitudeVrmsEditField = uieditfield(app.TestsettingsPanel, 'numeric');
            app.AmplitudeVrmsEditField.Limits = [0.001 1];
            app.AmplitudeVrmsEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVrmsEditFieldValueChanged, true);
            app.AmplitudeVrmsEditField.Value = 0.05;
            app.range00011Label = uilabel(app.TestsettingsPanel);
            app.range00011Label.Text = 'range: 0.001, 1';
            app.CH1InputButtonGroup = uibuttongroup(app.TestsettingsPanel);
            app.CH1InputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CH1InputButtonGroupSelectionChanged, true);
            app.CH1InputButtonGroup.Title = 'CH1 (Input)';
            app.OhmButton = uiradiobutton(app.CH1InputButtonGroup);
            app.OhmButton.Text = '50 Ohm';
            app.OhmButton.Value = true;
            app.MOhmButton = uiradiobutton(app.CH1InputButtonGroup);
            app.MOhmButton.Text = '1 MOhm';
            app.CH3OutputButtonGroup = uibuttongroup(app.TestsettingsPanel);
            app.CH3OutputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CH3OutputButtonGroupSelectionChanged, true);
            app.CH3OutputButtonGroup.Title = 'CH3 (Output)';
            app.OhmButton_2 = uiradiobutton(app.CH3OutputButtonGroup);
            app.OhmButton_2.Text = '50 Ohm';
            app.OhmButton_2.Value = true;
            app.MOhmButton_2 = uiradiobutton(app.CH3OutputButtonGroup);
            app.MOhmButton_2.Text = '1 MOhm';
            app.OscilloscopeterminationLabel = uilabel(app.TestsettingsPanel);
            app.OscilloscopeterminationLabel.Text = 'Oscilloscope termination';
            app.MDO34GenSignalAnalyzerToolLabel = uilabel(app.UIFigure);
            app.MDO34GenSignalAnalyzerToolLabel.Text = 'MDO34/Gen Signal Analyzer Tool';
            app.ChooseGeneratorButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseGeneratorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseGeneratorButtonGroupSelectionChanged, true);
            app.ChooseGeneratorButtonGroup.Title = 'Choose Generator';
            app.A33250Button = uiradiobutton(app.ChooseGeneratorButtonGroup);
            app.A33250Button.Text = '33250A';
            app.SML03Button = uiradiobutton(app.ChooseGeneratorButtonGroup);
            app.SML03Button.Text = 'SML 03';
            app.SML03Button.Value = true;
            app.Hz80MHzLabel = uilabel(app.ChooseGeneratorButtonGroup);
            app.Hz80MHzLabel.Text = '10Hz - 80 MHz';
            app.kHz200MHzLabel = uilabel(app.ChooseGeneratorButtonGroup);
            app.kHz200MHzLabel.Text = '9kHz - 200 MHz';
            app.LabelGENAddress = uilabel(app.UIFigure);
            app.LabelGENAddress.Text = 'Gen VISA Address:';
            app.ScopePanel = uipanel(app.UIFigure);
            app.ScopePanel.Title = 'Scope';
            app.FreqHzEditFieldLabel = uilabel(app.ScopePanel);
            app.FreqHzEditFieldLabel.Text = 'Freq (Hz)';
            app.VoutVinLabel = uilabel(app.ScopePanel);
            app.VoutVinLabel.Text = '|Vout/Vin| = ';
            app.PhaseLabel = uilabel(app.ScopePanel);
            app.PhaseLabel.Text = 'Phase = ';
            app.FreqHzEditField = uieditfield(app.ScopePanel, 'numeric');
            app.FreqHzEditField.Limits = [10 200000000];
            app.FreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @FreqHzEditFieldValueChanged, true);
            app.FreqHzEditField.Value = 10000000;
            app.range102E8Label = uilabel(app.ScopePanel);
            app.range102E8Label.Text = 'range: 10, 2E+8';
            app.InputButtonGroup = uibuttongroup(app.ScopePanel);
            app.InputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputButtonGroupSelectionChanged, true);
            app.InputButtonGroup.Title = 'Input';
            app.SineButton = uiradiobutton(app.InputButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.SquareButton = uiradiobutton(app.InputButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SweepPanel = uipanel(app.UIFigure);
            app.SweepPanel.Title = 'Sweep';
            app.range101E8Label = uilabel(app.SweepPanel);
            app.range101E8Label.Text = 'range: 10, 1E+8';
            app.StartFreqEditFieldLabel = uilabel(app.SweepPanel);
            app.StartFreqEditFieldLabel.Text = 'Start Freq';
            app.StartFreqEditField = uieditfield(app.SweepPanel, 'numeric');
            app.StartFreqEditField.Limits = [10 100000000];
            app.StartFreqEditField.Value = 10000000;
            app.StopFreqEditFieldLabel = uilabel(app.SweepPanel);
            app.StopFreqEditFieldLabel.Text = 'Stop Freq';
            app.StopFreqEditField = uieditfield(app.SweepPanel, 'numeric');
            app.StopFreqEditField.Limits = [1000 200000000];
            app.StopFreqEditField.ValueDisplayFormat = '%11.6g';
            app.StopFreqEditField.Value = 80000000;
            app.PointsDecadeEditFieldLabel = uilabel(app.SweepPanel);
            app.PointsDecadeEditFieldLabel.Text = 'Points/Decade';
            app.PointsDecadeEditField = uieditfield(app.SweepPanel, 'numeric');
            app.PointsDecadeEditField.Limits = [1 100];
            app.PointsDecadeEditField.Value = 5;
            app.range10002E8Label = uilabel(app.SweepPanel);
            app.range10002E8Label.Text = 'range: 1000, 2E+8';
            app.range1100Label = uilabel(app.SweepPanel);
            app.range1100Label.Text = 'range: 1, 100';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MDO34Gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end